classdef BraidCAM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % BraidCAM
        ModelResultsPanel       matlab.ui.container.Panel          % Elasti...
        UIAxesModulus           matlab.ui.control.UIAxes           % Elasti...
        UIAxesShear             matlab.ui.control.UIAxes           % Shear ...
        LabelNumericEditField2  matlab.ui.control.Label            % Longit...
        ExxField                matlab.ui.control.NumericEditField % [-Inf ...
        Label7                  matlab.ui.control.Label            % Transv...
        EyyField                matlab.ui.control.NumericEditField % [-Inf ...
        Label8                  matlab.ui.control.Label            % Out-of...
        EzzField                matlab.ui.control.NumericEditField % [-Inf ...
        Label9                  matlab.ui.control.Label            % In-Pla...
        GxyField                matlab.ui.control.NumericEditField % [-Inf ...
        Label10                 matlab.ui.control.Label            % Out-of...
        GyzField                matlab.ui.control.NumericEditField % [-Inf ...
        Label11                 matlab.ui.control.Label            % Out-of...
        GzxField                matlab.ui.control.NumericEditField % [-Inf ...
        LabelLamp               matlab.ui.control.Label            % Exx
        Lamp                    matlab.ui.control.Lamp            
        Label12                 matlab.ui.control.Label            % Eyy
        Lamp2                   matlab.ui.control.Lamp            
        Label13                 matlab.ui.control.Label            % Ezz
        Lamp3                   matlab.ui.control.Lamp            
        Label14                 matlab.ui.control.Label            % Gxy
        Lamp4                   matlab.ui.control.Lamp            
        Label15                 matlab.ui.control.Label            % Gyz
        Lamp5                   matlab.ui.control.Lamp            
        Label16                 matlab.ui.control.Label            % Gzx
        Lamp6                   matlab.ui.control.Lamp            
        BraidAnalysis           matlab.ui.control.Button           % Start
        LabelEditField3         matlab.ui.control.Label            % Model ...
        ModelResultsFileField   matlab.ui.control.EditField       
        SaveResultsButton       matlab.ui.control.Button           % Save R...
        BraidGeometryPanel      matlab.ui.container.Panel          % Braid ...
        LabelNumericEditField   matlab.ui.control.Label            % Braid ...
        BraidAngleField         matlab.ui.control.NumericEditField % [0 Inf]
        Label                   matlab.ui.control.Label            % Min An...
        BraidAngleMinField      matlab.ui.control.NumericEditField % [0 90]
        Label6                  matlab.ui.control.Label            % Max An...
        BraidAngleMaxField      matlab.ui.control.NumericEditField % [0 90]
        Label2                  matlab.ui.control.Label            % Mandre...
        BraidMandrelDiameterField matlab.ui.control.NumericEditField % [0 Inf]
        Label3                  matlab.ui.control.Label            % Yarn W...
        BraidYarnWidthField     matlab.ui.control.NumericEditField % [0 Inf]
        Label4                  matlab.ui.control.Label            % Yarn T...
        BraidYarnThicknessField matlab.ui.control.NumericEditField % [0 Inf]
        Label5                  matlab.ui.control.Label            % # of y...
        BraidNumberYarnsField   matlab.ui.control.NumericEditField % [0 Inf]
        LabelDropDown           matlab.ui.control.Label            % Braid ...
        BraidPatternDropDown    matlab.ui.control.DropDown         % Diamon...
        DisplayBraidPattern     matlab.ui.control.Button          
        LabelDropDown2          matlab.ui.control.Label            % Braid ...
        BraidAngleSelect        matlab.ui.control.DropDown         % Braid ...
        Label32                 matlab.ui.control.Label            % Braid ...
        YarnPropertiesPanel     matlab.ui.container.Panel          % Yarn M...
        LabelNumericEditField3  matlab.ui.control.Label            % E1f (GPa)
        E1fField                matlab.ui.control.NumericEditField % [0 Inf]
        Label17                 matlab.ui.control.Label            % E2f (GPa)
        E2fField                matlab.ui.control.NumericEditField % [0 Inf]
        Label18                 matlab.ui.control.Label            % E3f (GPa)
        E3fField                matlab.ui.control.NumericEditField % [0 Inf]
        Label19                 matlab.ui.control.Label            % G12f (...
        G12fField               matlab.ui.control.NumericEditField % [0 Inf]
        Label20                 matlab.ui.control.Label            % G13f (...
        G13fField               matlab.ui.control.NumericEditField % [0 Inf]
        Label21                 matlab.ui.control.Label            % G23f (...
        G23fField               matlab.ui.control.NumericEditField % [0 Inf]
        Label22                 matlab.ui.control.Label            % nu12f
        nu12fField              matlab.ui.control.NumericEditField % [0 Inf]
        Label23                 matlab.ui.control.Label            % nu13f
        nu13fField              matlab.ui.control.NumericEditField % [0 Inf]
        Label24                 matlab.ui.control.Label            % nu23f
        nu23fField              matlab.ui.control.NumericEditField % [0 Inf]
        Label25                 matlab.ui.control.Label            % Em (GPa)
        EmField                 matlab.ui.control.NumericEditField % [0 Inf]
        Label26                 matlab.ui.control.Label            % Gm (GPa)
        GmField                 matlab.ui.control.NumericEditField % [0 Inf]
        Label27                 matlab.ui.control.Label            % num
        numField                matlab.ui.control.NumericEditField % [0 Inf]
        Label28                 matlab.ui.control.Label            % Matrix...
        Label29                 matlab.ui.control.Label            % Yarn P...
        Button                  matlab.ui.control.Button          
        AboutButton             matlab.ui.control.Button           % About
        Panel                   matlab.ui.container.Panel          % Load/S...
        LabelEditField          matlab.ui.control.Label            % Yarn/m...
        YarnPropertiesFileField matlab.ui.control.EditField       
        LoadYarnButton          matlab.ui.control.Button           % Load Y...
        Label30                 matlab.ui.control.Label            % Braid ...
        GeometryFileField       matlab.ui.control.EditField       
        LoadGeometryButton      matlab.ui.control.Button           % Load G...
        LabelEditField2         matlab.ui.control.Label            % Save Y...
        SaveYarnField           matlab.ui.control.EditField       
        Button2                 matlab.ui.control.Button           % Save Y...
        Button3                 matlab.ui.control.Button           % Save G...
        Label31                 matlab.ui.control.Label            % Save Y...
        SaveGeometryField       matlab.ui.control.EditField       
        Label33                 matlab.ui.control.Label            % Braid 
        Label34                 matlab.ui.control.Label            % A 
        Button4                 matlab.ui.control.Button          
        Label35                 matlab.ui.control.Label            % C
        Label36                 matlab.ui.control.Label            % omposite
        Label37                 matlab.ui.control.Label            % nalyti...
        Label38                 matlab.ui.control.Label            % M
        Label39                 matlab.ui.control.Label            % odel
    end


    properties (Access = private)
        braidType=1 % Variable for selecting different braiding patterns
        ExModel % Description
        EyModel % Description
        EzModel % Description
        GxyModel % Description
        GyzModel % Description
        GzxModel % Description
        plotAngle % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         app.Button.Icon = 'D:\Google Drive U of A\Melenka-PhDThesis\Appendix A-BraidModelCode\ModelCode\CompositeAppLogo_256.jpeg';
         app.Button4.Icon = 'D:\Google Drive U of A\Melenka-PhDThesis\Appendix A-BraidModelCode\ModelCode\MultipurposeComposites.jpeg';
         app.DisplayBraidPattern.Icon = 'D:\Google Drive U of A\Melenka-PhDThesis\Appendix A-BraidModelCode\ModelCode\diamond.jpeg';
        end

        % BraidAngleField value changed function
        function braidAngle(app)
            value = app.BraidAngleField.Value;
            
        end

        % BraidPatternDropDown value changed function
        function braidPatternSelect(app, event)
            value = app.BraidPatternDropDown.Value;
            braidPattern = value;

            if strcmp(braidPattern,'Diamond (1/1)')              
                app.DisplayBraidPattern.Icon ='D:\Google Drive U of A\Melenka-PhDThesis\Appendix A-BraidModelCode\ModelCode\diamond.jpeg';
                app.braidType = 1;
                
            end
            if strcmp(braidPattern,'Regular (2/2)')
                app.DisplayBraidPattern.Icon ='D:\Google Drive U of A\Melenka-PhDThesis\Appendix A-BraidModelCode\ModelCode\regular.jpeg';
                app.braidType = 2;
            end
            if strcmp(braidPattern,'Hercules (3/3)')
                app.DisplayBraidPattern.Icon ='D:\Google Drive U of A\Melenka-PhDThesis\Appendix A-BraidModelCode\ModelCode\hercules.jpeg';
                app.braidType = 3;
            end


        end

        % BraidAnalysis button pushed function
        function BraidAnalysisCB(app)


            angleSelect = app.BraidAngleSelect.Value;
            
            if strcmp(angleSelect, 'Braid Angle Range')
                minAngle = app.BraidAngleMinField.Value;
                maxAngle = app.BraidAngleMaxField.Value;
                angle = linspace(minAngle,maxAngle,100);
            end
            
            if strcmp(angleSelect, 'Single Braid Angle')
               angle = app.BraidAngleField.Value;
            end
            
            N = app.BraidNumberYarnsField.Value;
            n = N/2;
            b = app.BraidYarnWidthField.Value;
            a = app.BraidYarnThicknessField.Value;
            
            beta = 2*pi / N; % braid shift angle (rad)
            D = app.BraidMandrelDiameterField.Value;
            R = D/2; % mandrel size mm
            r0 = R+a; % nomial braid radius mm
            
            braidChoice = app.braidType;
            
            
            %Matrix material properties
            Em = app.EmField.Value;
            Gm = app.GmField.Value;
            num = app.numField.Value;
            %Yarn properties
            E1 = app.E1fField.Value;
            E2 = app.E2fField.Value;
            E3 = app.E3fField.Value;
            G12 = app.G12fField.Value;
            G13 = app.G13fField.Value;
            G23 = app.G23fField.Value;
            nu12 = app.nu12fField.Value;
            nu13 = app.nu13fField.Value;
            nu23 = app.nu23fField.Value;
            nu21 = nu12*(E2/E1);
            nu31 = nu13*(E3/E1);
            nu32 = nu23*(E3/E2);
            %initial transversely isotropic compliance matrix for yarns+epoxy
            S = [1/E1 -nu21/E2 -nu31/E3 0 0 0;...
                -nu12/E1 1/E2 -nu32/E3 0 0 0;...
                -nu13/E1 -nu23/E2 1/E3 0 0 0;...
                0 0 0 1/G23 0 0;...
                0 0 0 0 1/G13 0;...
                0 0 0 0 0 1/G12];


            %initial compliance matrix for epoxy
            Sm = [1/Em -num/Em -num/Em 0 0 0;...
                 -num/Em 1/Em -num/Em 0 0 0;...
                 -num/Em -num/Em 1/Em 0 0 0;...
                  0 0 0 1/Gm 0 0;...
                  0 0 0 0 1/Gm 0;...
                  0 0 0 0 0 1/Gm];
            
            [Ex, Ey, Ez, Gxy, Gyz, Gzx] = braidModel(S, Sm, angle, n, r0, a, b, beta, braidChoice);
            
            app.ExModel = Ex;
            app.EyModel = Ey;
            app.EzModel = Ez;
            
            app.GxyModel = Gxy;
            app.GyzModel = Gyz;
            app.GzxModel = Gzx;
            
            
            if strcmp(angleSelect, 'Braid Angle Range')
                app.plotAngle = angle;
                hold(app.UIAxesModulus, 'off');
                p1=plot(app.UIAxesModulus, app.plotAngle, app.ExModel);
                p1.Color = 'Black';
                hold(app.UIAxesModulus, 'on');
                p2=plot(app.UIAxesModulus, app.plotAngle, app.EyModel);
                p2.Color = 'Red';
                hold(app.UIAxesModulus, 'on');
                p3=plot(app.UIAxesModulus, app.plotAngle, app.EzModel);
                p3.Color = 'Blue';
                
                hold(app.UIAxesShear, 'off');
                p4=plot(app.UIAxesShear, app.plotAngle, app.GxyModel);
                p4.Color = [0.5 0.5 0.5];
                hold(app.UIAxesShear, 'on');
                p5=plot(app.UIAxesShear, app.plotAngle, app.GyzModel);
                p5.Color = 'Magenta';
                hold(app.UIAxesShear, 'on');
                p6=plot(app.UIAxesShear, app.plotAngle, app.GzxModel);
                p6.Color = 'Green';


                app.ExxField.Value = 0.0;
                app.EyyField.Value = 0.0;
                app.EzzField.Value = 0.0;
                
                app.GxyField.Value = 0.0;
                app.GyzField.Value = 0.0;
                app.GzxField.Value = 0.0;
            end
            
            if strcmp(angleSelect, 'Single Braid Angle')
                
                app.plotAngle = angle;
                hold(app.UIAxesModulus, 'off');
                p1=plot(app.UIAxesModulus, app.plotAngle, app.ExModel);
                p1.Color = 'Red';
                hold(app.UIAxesModulus, 'on');
                p2=plot(app.UIAxesModulus, app.plotAngle, app.EyModel);
                p2.Color = 'Black';
                hold(app.UIAxesModulus, 'on');
                p3=plot(app.UIAxesModulus, app.plotAngle, app.EzModel);
                p3.Color = 'Blue';
                
                hold(app.UIAxesShear, 'off');
                p4=plot(app.UIAxesShear, app.plotAngle, app.GxyModel);
                p4.Color = [0.5 0.5 0.5];
                hold(app.UIAxesShear, 'on');
                p5=plot(app.UIAxesShear, app.plotAngle, app.GyzModel);
                p5.Color = 'Magenta';
                hold(app.UIAxesShear, 'on');
                p6=plot(app.UIAxesShear, app.plotAngle, app.GzxModel);
                p6.Color = 'Green';


                app.ExxField.Value = app.ExModel;
                app.EyyField.Value = app.EyModel;
                app.EzzField.Value = app.EzModel;
                
                app.GxyField.Value = app.GxyModel;
                app.GyzField.Value = app.GyzModel;
                app.GzxField.Value = app.GzxModel;


            end
            
            app.SaveResultsButton.Enable = 'on';
            app.ModelResultsFileField.Enable = 'on';


        end

        % BraidAngleSelect value changed function
        function braidAngleSelect(app, event)
            value = app.BraidAngleSelect.Value;
            
            if strcmp(value, 'Braid Angle Range')
                app.BraidAngleMinField.Enable = 'on';
                app.BraidAngleMaxField.Enable = 'on';
                app.BraidAngleField.Enable = 'off';
            end
            
            if strcmp(value, 'Single Braid Angle')
                app.BraidAngleField.Enable = 'on';
                app.BraidAngleMinField.Enable = 'off';
                app.BraidAngleMaxField.Enable = 'off';
            end
            
        end

        % AboutButton button pushed function
        function AboutCB(app)
            msgbox({'Braided Composite Analytical Model' 'Author: Garrett Melenka' 'Email:gmelenka@ualberta.ca'}, 'About')
        end

        % LoadGeometryButton button pushed function
        function LoadGeometryCB(app)
            
            
            [fileName, pathName] = uigetfile('*.csv', 'Load Braid Geometry', pwd);


            name = fullfile(pathName,fileName);
                A = csvread(name,3);
            %Check to make sure correct file selected
            if length(A) == 8
                
                app.GeometryFileField.Value = fileName;
                app.BraidAngleField.Value = A(1);
                app.BraidAngleMinField.Value = A(2);
                app.BraidAngleMaxField.Value = A(3);
                app.BraidMandrelDiameterField.Value = A(4);
                app.BraidYarnWidthField.Value = A(5);
                app.BraidYarnThicknessField.Value = A(6);
                app.BraidNumberYarnsField.Value =A(7);
            
                if A(8) == 1
                    app.BraidPatternDropDown.Value = 'Diamond (1/1)';
                    app.braidType = 1;
                    app.DisplayBraidPattern.Icon ='diamond.png';
                elseif A(8) == 2
                    app.BraidPatternDropDown.Value = 'Regular (2/2)';
                    app.braidType = 2;
                    app.DisplayBraidPattern.Icon ='regular.png';
                elseif A(8) == 3
                    app.BraidPatternDropDown.Value = 'Hercules (3/3)';
                    app.braidType = 3;
                    app.DisplayBraidPattern.Icon ='hercules.png';
                end
            end
            if length(A) ~= 8
                msgbox('Incorrect file for braid geometry');
            end
            %fclose(fileID);
        end

        % LoadYarnButton button pushed function
        function LoadYarnCB(app)
            [fileName, pathName] = uigetfile('*.csv', 'Load Yarn Properties', pwd);


            name = fullfile(pathName,fileName);
            A = csvread(name,3);
            
            if length(A) == 12
                
                app.YarnPropertiesFileField.Value = fileName;
                app.E1fField.Value = A(1);
                app.E2fField.Value = A(2);
                app.E3fField.Value = A(3);
                app.G12fField.Value = A(4);
                app.G13fField.Value = A(5);
                app.G23fField.Value = A(6);
                app.nu12fField.Value =A(7);
                app.nu13fField.Value =A(8);
                app.nu23fField.Value =A(9);
                
                app.EmField.Value = A(10);
                app.GmField.Value = A(11);
                app.numField.Value = A(12);
            end
            if length(A) ~= 12
               msgbox('Incorrect File for Yarn Properties'); 
            end
        end

        % Button2 button pushed function
        function SaveYarnCB(app)
            [saveFileName, saveFilePath] = uiputfile('*.csv', 'Save yarn properties as', pwd);
            
            app.SaveYarnField.Value = saveFileName;
            
            saveName = fullfile(saveFilePath, saveFileName);
            
            output1 = app.E1fField.Value;
            output2 = app.E2fField.Value;
            output3 = app.E3fField.Value;
            output4 = app.G12fField.Value;
            output5 = app.G13fField.Value;
            output6 = app.G23fField.Value;
            output7 = app.nu12fField.Value;
            output8 = app.nu13fField.Value;
            output9 = app.nu23fField.Value;
            
            output10 = app.EmField.Value;
            output11 = app.GmField.Value;
            output12 = app.numField.Value;
            
            output = [output1, output2, output3, output4, output5, output6, output7, output8, output9, output10, output11, output12];


            header1 = 'Braided Composite Yarn + Matrix Properties';
            header2 = 'E1f, E2f, E3f, G12f, G13f, G23f, nu12f, nu13f, nu23f, Em, Gm, num';
            
            fid = fopen(saveName, 'w');
            
            fprintf(fid, '%s\n%s\n\n', header1, header2);
            
            
            fprintf(fid, '%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f',...
                output(1),output(2),output(3),output(4),output(5),output(6),output(7),output(8),output(9),output(10),output(11),output(12)); 
            
            
            fclose(fid);
            
        end

        % Button3 button pushed function
        function SaveGeometryCB(app)
            [saveFileName, saveFilePath] = uiputfile('*.csv', 'Save braid geometry as', pwd);
            
            app.SaveGeometryField.Value = saveFileName;
            
            saveName = fullfile(saveFilePath, saveFileName);
            
            output1 = app.BraidAngleField.Value;
            output2 = app.BraidAngleMinField.Value;
            output3 = app.BraidAngleMaxField.Value;
            output4 = app.BraidMandrelDiameterField.Value;
            output5 = app.BraidYarnWidthField.Value;
            output6 = app.BraidYarnThicknessField.Value;
            output7 = app.BraidNumberYarnsField.Value;
            output8 = app.braidType;
            
            
            output = [output1, output2, output3, output4, output5, output6, output7, output8];


            header1 = 'Braided Composite Geometry';
            header2 = 'Braid Angle, Braid Angle Min, Braid Angle Max, Mandrel Diameter, Yarn Width, Yarn Thickness, Number of Yarns, Braid Pattern';
            
            fid = fopen(saveName, 'w');
            
            fprintf(fid, '%s\n%s\n\n', header1, header2);
            
            
            fprintf(fid, '%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f',...
                output(1),output(2),output(3),output(4),output(5),output(6),output(7),output(8)); 
            
            fclose(fid);
        end

        % SaveResultsButton button pushed function
        function SaveModelResultsCB(app)
            [saveFileName, saveFilePath] = uiputfile('*.csv', 'Save model results', pwd);    
            
            app.ModelResultsFileField.Value = saveFileName;
            
            saveName = fullfile(saveFilePath, saveFileName);
            
            modelDate = date;
            
            %save yarn properties
            output1 = app.E1fField.Value;
            output2 = app.E2fField.Value;
            output3 = app.E3fField.Value;
            output4 = app.G12fField.Value;
            output5 = app.G13fField.Value;
            output6 = app.G23fField.Value;
            output7 = app.nu12fField.Value;
            output8 = app.nu13fField.Value;
            output9 = app.nu23fField.Value;
            
            output10 = app.EmField.Value;
            output11 = app.GmField.Value;
            output12 = app.numField.Value;
            
            output = [output1, output2, output3, output4, output5, output6, output7, output8, output9, output10, output11, output12];


            header1 = 'Braided Composite Model Results';
            header2 = 'Yarn/Matrix Properties';
            header3 = 'E1f, E2f, E3f, G12f, G13f, G23f, nu12f, nu13f, nu23f, Em, Gm, num';
            
            fid = fopen(saveName, 'w');
            
            fprintf(fid, '%s\n%s\n%s\n\n', header1, header2,header3);
            
            
            fprintf(fid, '%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f\n',...
                output(1),output(2),output(3),output(4),output(5),output(6),output(7),output(8),output(9),output(10),output(11),output(12));
            
            %save geometry    
            outputGeometry1 = app.BraidAngleField.Value;
            outputGeometry2 = app.BraidAngleMinField.Value;
            outputGeometry3 = app.BraidAngleMaxField.Value;
            outputGeometry4 = app.BraidMandrelDiameterField.Value;
            outputGeometry5 = app.BraidYarnWidthField.Value;
            outputGeometry6 = app.BraidYarnThicknessField.Value;
            outputGeometry7 = app.BraidNumberYarnsField.Value;
            outputGeometry8 = app.braidType;
            
            
            outputGeometry = [outputGeometry1, outputGeometry2, outputGeometry3, outputGeometry4, outputGeometry5, outputGeometry6, outputGeometry7, outputGeometry8];


            header4 = 'Braided Composite Geometry';
            header5 = 'Braid Angle, Braid Angle Min, Braid Angle Max, Mandrel Diameter, Yarn Width, Yarn Thickness, Number of Yarns, Braid Pattern';


            fprintf(fid, '\n%s\n%s\n\n', header4, header5);
            
            
            fprintf(fid, '%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f',...
                outputGeometry(1),outputGeometry(2),outputGeometry(3),outputGeometry(4),outputGeometry(5),outputGeometry(6),outputGeometry(7),outputGeometry(8));     
            
                
            header6 = 'Model Results';
            header7 = 'Angle, Exx, Eyy, Ezz, Gxy, Gyz, Gzx';
            
            fprintf(fid, '\n%s\n%s\n\n', header6, header7);
            
            for i = 1:length(app.plotAngle)
                
               fprintf(fid, '%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f,%0.3f\n',...
               app.plotAngle(i), app.ExModel(i), app.EyModel(i), app.EzModel(i), app.GxyModel(i), app.GyzModel(i), app.GzxModel(i));
            end
            
            fclose(fid);
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'BraidCAM';
            setAutoResize(app, app.UIFigure, true)
            app.ModelResultsPanel = uipanel(app.UIFigure);
            app.ModelResultsPanel.Title = 'Elastic Moduli';
            app.ModelResultsPanel.Units = 'pixels';
            app.UIAxesModulus = uiaxes(app.ModelResultsPanel);
            title(app.UIAxesModulus, 'Elastic Modulus');
            xlabel(app.UIAxesModulus, 'Braid Angle (\it{\theta})');
            ylabel(app.UIAxesModulus, 'Elastic Modulus (GPa)');
            app.UIAxesShear = uiaxes(app.ModelResultsPanel);
            title(app.UIAxesShear, 'Shear Modulus');
            xlabel(app.UIAxesShear, 'Braid Angle (\it{\theta})');
            ylabel(app.UIAxesShear, 'Shear Modulus (GPa)');
            app.LabelNumericEditField2 = uilabel(app.ModelResultsPanel);
            app.LabelNumericEditField2.Text = 'Longitudinal Modulus Exx (GPa)';
            app.ExxField = uieditfield(app.ModelResultsPanel, 'numeric');
            app.ExxField.Editable = 'off';
            app.Label7 = uilabel(app.ModelResultsPanel);
            app.Label7.Text = 'Transverse Modulus Eyy (GPa)';
            app.EyyField = uieditfield(app.ModelResultsPanel, 'numeric');
            app.EyyField.Editable = 'off';
            app.Label8 = uilabel(app.ModelResultsPanel);
            app.Label8.Text = 'Out-of-Plane Modulus Ezz (GPa)';
            app.EzzField = uieditfield(app.ModelResultsPanel, 'numeric');
            app.EzzField.Editable = 'off';
            app.Label9 = uilabel(app.ModelResultsPanel);
            app.Label9.Text = 'In-Plane Shear Modulus Gxy (GPa)';
            app.GxyField = uieditfield(app.ModelResultsPanel, 'numeric');
            app.GxyField.Editable = 'off';
            app.Label10 = uilabel(app.ModelResultsPanel);
            app.Label10.Text = 'Out-of Plane Modulus Gyz (GPa)';
            app.GyzField = uieditfield(app.ModelResultsPanel, 'numeric');
            app.GyzField.Editable = 'off';
            app.Label11 = uilabel(app.ModelResultsPanel);
            app.Label11.Text = 'Out-of-Plane Modulus Gzx (GPa)';
            app.GzxField = uieditfield(app.ModelResultsPanel, 'numeric');
            app.GzxField.Editable = 'off';
            app.LabelLamp = uilabel(app.ModelResultsPanel);
            app.LabelLamp.Text = 'Exx';
            app.Lamp = uilamp(app.ModelResultsPanel);
            app.Label12 = uilabel(app.ModelResultsPanel);
            app.Label12.Text = 'Eyy';
            app.Lamp2 = uilamp(app.ModelResultsPanel);
            app.Label13 = uilabel(app.ModelResultsPanel);
            app.Label13.Text = 'Ezz';
            app.Lamp3 = uilamp(app.ModelResultsPanel);
            app.Label14 = uilabel(app.ModelResultsPanel);
            app.Label14.Text = 'Gxy';
            app.Lamp4 = uilamp(app.ModelResultsPanel);
            app.Label15 = uilabel(app.ModelResultsPanel);
            app.Label15.Text = 'Gyz';
            app.Lamp5 = uilamp(app.ModelResultsPanel);
            app.Label16 = uilabel(app.ModelResultsPanel);
            app.Label16.Text = 'Gzx';
            app.Lamp6 = uilamp(app.ModelResultsPanel);
            app.BraidAnalysis = uibutton(app.ModelResultsPanel, 'push');
            app.BraidAnalysis.ButtonPushedFcn = createCallbackFcn(app, @BraidAnalysisCB);
            app.BraidAnalysis.Text = 'Start';
            app.LabelEditField3 = uilabel(app.ModelResultsPanel);
            app.LabelEditField3.Text = 'Model Results File';
            app.ModelResultsFileField = uieditfield(app.ModelResultsPanel, 'text');
            app.ModelResultsFileField.Enable = 'off';
            app.SaveResultsButton = uibutton(app.ModelResultsPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelResultsCB);
            app.SaveResultsButton.Enable = 'off';
            app.SaveResultsButton.Text = 'Save Results';
            app.BraidGeometryPanel = uipanel(app.UIFigure);
            app.BraidGeometryPanel.Title = 'Braid Geometry Input';
            app.BraidGeometryPanel.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.BraidGeometryPanel);
            app.LabelNumericEditField.Text = 'Braid Angle (deg)';
            app.BraidAngleField = uieditfield(app.BraidGeometryPanel, 'numeric');
            app.BraidAngleField.ValueChangedFcn = createCallbackFcn(app, @braidAngle);
            app.BraidAngleField.Limits = [0 Inf];
            app.BraidAngleField.Enable = 'off';
            app.BraidAngleField.Value = 45;
            app.Label = uilabel(app.BraidGeometryPanel);
            app.Label.Text = 'Min Angle (deg)';
            app.BraidAngleMinField = uieditfield(app.BraidGeometryPanel, 'numeric');
            app.BraidAngleMinField.Limits = [0 90];
            app.BraidAngleMinField.Value = 30;
            app.Label6 = uilabel(app.BraidGeometryPanel);
            app.Label6.Text = 'Max Angle (deg)';
            app.BraidAngleMaxField = uieditfield(app.BraidGeometryPanel, 'numeric');
            app.BraidAngleMaxField.Limits = [0 90];
            app.BraidAngleMaxField.Value = 60;
            app.Label2 = uilabel(app.BraidGeometryPanel);
            app.Label2.Text = 'Mandrel Diameter (mm)';
            app.BraidMandrelDiameterField = uieditfield(app.BraidGeometryPanel, 'numeric');
            app.BraidMandrelDiameterField.Limits = [0 Inf];
            app.BraidMandrelDiameterField.Value = 11.1;
            app.Label3 = uilabel(app.BraidGeometryPanel);
            app.Label3.Text = 'Yarn Width Wy (mm)';
            app.BraidYarnWidthField = uieditfield(app.BraidGeometryPanel, 'numeric');
            app.BraidYarnWidthField.Limits = [0 Inf];
            app.BraidYarnWidthField.Value = 3.1;
            app.Label4 = uilabel(app.BraidGeometryPanel);
            app.Label4.Text = 'Yarn Thickness (mm)';
            app.BraidYarnThicknessField = uieditfield(app.BraidGeometryPanel, 'numeric');
            app.BraidYarnThicknessField.Limits = [0 Inf];
            app.BraidYarnThicknessField.Value = 0.38;
            app.Label5 = uilabel(app.BraidGeometryPanel);
            app.Label5.Text = '# of yarns (N)';
            app.BraidNumberYarnsField = uieditfield(app.BraidGeometryPanel, 'numeric');
            app.BraidNumberYarnsField.Limits = [0 Inf];
            app.BraidNumberYarnsField.Value = 18;
            app.LabelDropDown = uilabel(app.BraidGeometryPanel);
            app.LabelDropDown.Text = 'Braid Pattern';
            app.BraidPatternDropDown = uidropdown(app.BraidGeometryPanel);
            app.BraidPatternDropDown.Items = {'Diamond (1/1)', 'Regular (2/2)', 'Hercules (3/3)'};
            app.BraidPatternDropDown.ValueChangedFcn = createCallbackFcn(app, @braidPatternSelect, true);
            app.BraidPatternDropDown.Value = 'Diamond (1/1)';
            app.DisplayBraidPattern = uibutton(app.BraidGeometryPanel, 'push');
            app.DisplayBraidPattern.Text = '';
            app.LabelDropDown2 = uilabel(app.BraidGeometryPanel);
            app.LabelDropDown2.Text = 'Braid Angle Option';
            app.BraidAngleSelect = uidropdown(app.BraidGeometryPanel);
            app.BraidAngleSelect.Items = {'Braid Angle Range', 'Single Braid Angle'};
            app.BraidAngleSelect.ValueChangedFcn = createCallbackFcn(app, @braidAngleSelect, true);
            app.BraidAngleSelect.Value = 'Braid Angle Range';
            app.Label32 = uilabel(app.BraidGeometryPanel);
            app.Label32.Text = 'Braid Pattern Schematic';
            app.YarnPropertiesPanel = uipanel(app.UIFigure);
            app.YarnPropertiesPanel.Title = 'Yarn Mechanical Properties';
            app.YarnPropertiesPanel.Units = 'pixels';
            app.LabelNumericEditField3 = uilabel(app.YarnPropertiesPanel);
            app.LabelNumericEditField3.Text = 'E1f (GPa)';
            app.E1fField = uieditfield(app.YarnPropertiesPanel, 'numeric');
            app.E1fField.Limits = [0 Inf];
            app.E1fField.Value = 79.7;
            app.Label17 = uilabel(app.YarnPropertiesPanel);
            app.Label17.Text = 'E2f (GPa)';
            app.E2fField = uieditfield(app.YarnPropertiesPanel, 'numeric');
            app.E2fField.Limits = [0 Inf];
            app.E2fField.Value = 5.9;
            app.Label18 = uilabel(app.YarnPropertiesPanel);
            app.Label18.Text = 'E3f (GPa)';
            app.E3fField = uieditfield(app.YarnPropertiesPanel, 'numeric');
            app.E3fField.Limits = [0 Inf];
            app.E3fField.Value = 5.9;
            app.Label19 = uilabel(app.YarnPropertiesPanel);
            app.Label19.Text = 'G12f (GPa)';
            app.G12fField = uieditfield(app.YarnPropertiesPanel, 'numeric');
            app.G12fField.Limits = [0 Inf];
            app.G12fField.Value = 1.5;
            app.Label20 = uilabel(app.YarnPropertiesPanel);
            app.Label20.Text = 'G13f (GPa)';
            app.G13fField = uieditfield(app.YarnPropertiesPanel, 'numeric');
            app.G13fField.Limits = [0 Inf];
            app.G13fField.Value = 1.5;
            app.Label21 = uilabel(app.YarnPropertiesPanel);
            app.Label21.Text = 'G23f (GPa)';
            app.G23fField = uieditfield(app.YarnPropertiesPanel, 'numeric');
            app.G23fField.Limits = [0 Inf];
            app.G23fField.Value = 2;
            app.Label22 = uilabel(app.YarnPropertiesPanel);
            app.Label22.Text = 'nu12f';
            app.nu12fField = uieditfield(app.YarnPropertiesPanel, 'numeric');
            app.nu12fField.Limits = [0 Inf];
            app.nu12fField.Value = 0.3;
            app.Label23 = uilabel(app.YarnPropertiesPanel);
            app.Label23.Text = 'nu13f';
            app.nu13fField = uieditfield(app.YarnPropertiesPanel, 'numeric');
            app.nu13fField.Limits = [0 Inf];
            app.nu13fField.Value = 0.3;
            app.Label24 = uilabel(app.YarnPropertiesPanel);
            app.Label24.Text = 'nu23f';
            app.nu23fField = uieditfield(app.YarnPropertiesPanel, 'numeric');
            app.nu23fField.Limits = [0 Inf];
            app.nu23fField.Value = 0.475;
            app.Label25 = uilabel(app.YarnPropertiesPanel);
            app.Label25.Text = 'Em (GPa)';
            app.EmField = uieditfield(app.YarnPropertiesPanel, 'numeric');
            app.EmField.Limits = [0 Inf];
            app.EmField.Value = 3.5;
            app.Label26 = uilabel(app.YarnPropertiesPanel);
            app.Label26.Text = 'Gm (GPa)';
            app.GmField = uieditfield(app.YarnPropertiesPanel, 'numeric');
            app.GmField.Limits = [0 Inf];
            app.GmField.Value = 1.3;
            app.Label27 = uilabel(app.YarnPropertiesPanel);
            app.Label27.Text = 'num';
            app.numField = uieditfield(app.YarnPropertiesPanel, 'numeric');
            app.numField.Limits = [0 Inf];
            app.numField.Value = 0.3;
            app.Label28 = uilabel(app.YarnPropertiesPanel);
            app.Label28.Text = 'Matrix Properties';
            app.Label29 = uilabel(app.YarnPropertiesPanel);
            app.Label29.Text = 'Yarn Properties';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.AboutButton = uibutton(app.UIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutCB);
            app.AboutButton.Text = 'About';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Load/Save Data';
            app.Panel.Units = 'pixels';
            app.LabelEditField = uilabel(app.Panel);
            app.LabelEditField.Text = 'Yarn/matrix Properties File';
            app.YarnPropertiesFileField = uieditfield(app.Panel, 'text');
            app.LoadYarnButton = uibutton(app.Panel, 'push');
            app.LoadYarnButton.ButtonPushedFcn = createCallbackFcn(app, @LoadYarnCB);
            app.LoadYarnButton.Text = 'Load Yarn/Matrix Properties';
            app.Label30 = uilabel(app.Panel);
            app.Label30.Text = 'Braid Geometry File';
            app.GeometryFileField = uieditfield(app.Panel, 'text');
            app.LoadGeometryButton = uibutton(app.Panel, 'push');
            app.LoadGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGeometryCB);
            app.LoadGeometryButton.Text = 'Load Geometry';
            app.LabelEditField2 = uilabel(app.Panel);
            app.LabelEditField2.Text = 'Save Yarn/matrix Properties';
            app.SaveYarnField = uieditfield(app.Panel, 'text');
            app.Button2 = uibutton(app.Panel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @SaveYarnCB);
            app.Button2.Text = 'Save Yarn/Matrix Properties';
            app.Button3 = uibutton(app.Panel, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @SaveGeometryCB);
            app.Button3.Text = 'Save Geometry';
            app.Label31 = uilabel(app.Panel);
            app.Label31.Text = 'Save Yarn/matrix Properties';
            app.SaveGeometryField = uieditfield(app.Panel, 'text');
            app.Label33 = uilabel(app.UIFigure);
            app.Label33.Text = 'Braid ';
            app.Label34 = uilabel(app.UIFigure);
            app.Label34.Text = 'A ';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.Enable = 'off';
            app.Button4.Text = '';
            app.Label35 = uilabel(app.UIFigure);
            app.Label35.Text = 'C';
            app.Label36 = uilabel(app.UIFigure);
            app.Label36.Text = 'omposite';
            app.Label37 = uilabel(app.UIFigure);
            app.Label37.Text = 'nalytical ';
            app.Label38 = uilabel(app.UIFigure);
            app.Label38.Text = 'M';
            app.Label39 = uilabel(app.UIFigure);
            app.Label39.Text = 'odel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BraidCAM()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end